classdef SignalProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        AerodynamicsListBox          matlab.ui.control.ListBox
        AerodynamicsListBoxLabel     matlab.ui.control.Label
        GraphicsConfigurationsPanel  matlab.ui.container.Panel
        DPIEditField                 matlab.ui.control.NumericEditField
        DPIEditFieldLabel            matlab.ui.control.Label
        SaveImageButton              matlab.ui.control.Button
        YLabel_InterpreterDropDown   matlab.ui.control.DropDown
        XLabel_InterpreterDropDown   matlab.ui.control.DropDown
        title_InterpreterDropDown    matlab.ui.control.DropDown
        TitleEditField               matlab.ui.control.EditField
        TitleEditFieldLabel          matlab.ui.control.Label
        YLabelEditField              matlab.ui.control.EditField
        YLabelEditFieldLabel         matlab.ui.control.Label
        XLabelEditField              matlab.ui.control.EditField
        XLabelEditFieldLabel         matlab.ui.control.Label
        NonDimensionalEditField      matlab.ui.control.NumericEditField
        NondimensionalformCheckBox   matlab.ui.control.CheckBox
        Yf_LimitEditField            matlab.ui.control.NumericEditField
        Y0_LimitEditField            matlab.ui.control.NumericEditField
        Xf_LimitEditField            matlab.ui.control.NumericEditField
        X0_LimitEditField            matlab.ui.control.NumericEditField
        AutoamplitudeaxisCheckBox    matlab.ui.control.CheckBox
        AutofrequencyaxisCheckBox    matlab.ui.control.CheckBox
        openButton                   matlab.ui.control.Button
        deleteButton                 matlab.ui.control.Button
        NoiseListBox                 matlab.ui.control.ListBox
        NoiseListBoxLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes_torque                matlab.ui.control.UIAxes
        UIAxes_thrust                matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function storeData(app, files, nameCase)
            % Configuration parameters
            rpm =  cell(1, length(files));
            propeller = cell(1, length(files));
            U0 = cell(1, length(files));
            
            % Store frequency and time data
            data_aero = {};
            labelsAero = {};
            data_freq = cell(length(files),1);
            fieldnames = cell(length(files),1);
            for i=1:length(files)
                [root_path, ~,~] = fileparts(files{i});
                root_root_path = fileparts(root_path);
                root_aero_path = fullfile(root_root_path, 'Aerodinâmica');

                % Load noise data
                file = dir(fullfile(root_path, nameCase{i}, 'CBF', strcat(['Spectrum*', nameCase{i}, '.mat'])));

                data_freq{i} = load(fullfile(root_path, nameCase{i}, 'CBF', file(1).name));
                % fields_time{i} = load(fullfile(path_cases, files{i}));
                name = strrep(nameCase, ' ', '_');
                name = strrep(name, '.', '_');
                fieldnames{i} = strcat(['H_',name]);
                
                % Get run parameters
                name_split = split(nameCase{i}, '_');
                if any(strfind(nameCase{i}, 'V'))
                    value = split(nameCase{i}, 'V');
                    U0{i} = strcat([value{end}, ' m/s']);
                else
                    U0{i} = '0 m/s';
                end
                rpm{i} = name_split{2};
                propeller{i} = name_split{1};
                
                 % Load Aero data
                 [ex, aeroData] = loadAeroData(app,root_aero_path, nameCase{i});
                 if ex
                    data_aero{i} = aeroData ;
                    labelsAero{i} = strcat([rpm{i}, ' - ', U0{i}, ' - ', propeller{i}]);
                 end
            end
            labels = cellfun(@(x, y, z) strcat([x, ' - ', y, ' - ', z]), rpm, U0, propeller,  'UniformOutput', false);
            

            % save data in ListBox object
            if isempty(app.NoiseListBox.Items)
                app.NoiseListBox.Items = labels;
                app.NoiseListBox.ItemsData = data_freq;
            else
                shift = length(labels);
                app.NoiseListBox.Items(end+1:end+shift) = labels;
                app.NoiseListBox.ItemsData(end+1:end+shift) = data_freq;
            end

            % Separate aerodynamic data per U0 case
            [groups, labelsAero] = splitAerodynamicData(app, data_aero, labelsAero);

            if isempty(app.AerodynamicsListBox.Items)
                app.AerodynamicsListBox.Items = labelsAero;
                app.AerodynamicsListBox.ItemsData = groups.values;
            else
                shift = length(labelsAero);
                app.AerodynamicsListBox.Items(end+1:end+shift) = labelsAero;
                app.AerodynamicsListBox.ItemsData(end+1:end+shift) = groups.values;
            end
        end
       
        
        function [ex_avg, aeroData] = loadAeroData(app, root_aero_path, nameCase)
            aeroFile = fullfile(root_aero_path, strcat([nameCase, '.txt']));
            aeroDir = fullfile(root_aero_path, nameCase);
            if exist(aeroFile) == 2
                ex_avg = true;
                aeroData = readtable(aeroFile, ReadVariableNames=false);
                aeroData = table2array(aeroData);
                aeroData = mean(aeroData, 1);
                
                if exist(aeroDir) == 7
                    % iterar na pasta e salvando os arquivos
                end
                
            else
                ex_avg = false;
                aeroData = 0;
            end
            
        end
        
        function [groups, labels] = splitAerodynamicData(app, data_aero, labeslAero)
            groups = containers.Map('KeyType', 'double', 'ValueType', 'any');
            labels = {};
            for i=1:length(labeslAero)
                parts = strsplit(labeslAero{i}, '-');
                U0 = sscanf(parts{2},'%f');
                propeller = parts{3};

                if isKey(groups, U0)
                    groups(U0) = [groups(U0); data_aero{i}];
                else
                    groups(U0) = [data_aero{i}];
                    labels{end+1} = strcat([num2str(U0), ' m/s -', propeller]);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            % Load cases
            [files, path_case] = uigetfile("MultiSelect","on");
            
            if ~iscell(files)
                files = {files};
            end
            
            % Get full path files and nameCase to use in listbox
            nameCase = cell(1, length(files));
            for i=1:length(files)
                files{i} = fullfile(path_case, files{i});
                [~, nameCase{i}, ~] = fileparts(files{i});
            end
            app.deleteButton.Enable="on";
            app.GraphicsConfigurationsPanel.Enable = 'on';
            storeData(app, files, nameCase);
        end

        % Value changed function: NoiseListBox
        function NoiseListBoxValueChanged(app, event)
            % PLOT FUNCTION
            %Clear axes
            cla(app.UIAxes)
            
            % Get values
            values = app.NoiseListBox.Value;
            valuesIndex = app.NoiseListBox.ValueIndex;
            
            if ~isvector(valuesIndex)
                valuesIndex = [valuesIndex];
            end

            legendNames = cell(1 , length(valuesIndex));
            
            % Plot
            for i=1:length(values)
                value = values{i};
                if i==2
                    hold(app.UIAxes, 'on');
                end
                if app.NondimensionalformCheckBox.Value == 1
                    refx_value = app.NonDimensionalEditField.Value;
                else
                    refx_value = 1;
                end
                semilogx(app.UIAxes, value.frequency/refx_value, value.Spectrum, 'LineWidth',1.5);
                legendNames{i} = app.NoiseListBox.Items{valuesIndex(i)};
            end
            hold(app.UIAxes, 'off');
            
            if app.AutofrequencyaxisCheckBox.Value == 1
                xlim(app.UIAxes, "auto");
            else
                xlim(app.UIAxes, [app.X0_LimitEditField.Value, app.Xf_LimitEditField.Value]);
            end

            if app.AutoamplitudeaxisCheckBox.Value == 1
                ylim(app.UIAxes, "auto");
            else
                ylim(app.UIAxes, [app.Y0_LimitEditField.Value, app.Yf_LimitEditField.Value]);
            end
            % Add legend and 
            leg = legend(app.UIAxes, legendNames, 'Interpreter', 'none');
            % set(leg, 'ItemHitFcn', @(~, event) handleLegendClick(app, event));

        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            % Get current ListBox's itens and data (ItemData)
            allItems = app.NoiseListBox.Items;
            allItemData = app.NoiseListBox.ItemsData;
            % selectedItems = app.NoiseListBox.Value;
        
            % Verify the selected items
            selectedIndices = app.NoiseListBox.ValueIndex;

            % Create a list of remaining indices (non-selected ones)
            remainingIndices = setdiff(1:length(allItems), selectedIndices);
            
            % Remove selected items of listbox
            remainingItems = allItems(remainingIndices);
            remainingItemsData = allItemData(remainingIndices);
            
            % Update ListBox
            app.NoiseListBox.Items = remainingItems;
            app.NoiseListBox.ItemsData = remainingItemsData;
            
            % Clear axes
            cla(app.UIAxes)

            % Verify if all items was removed
            if isempty(remainingItems)
                app.deleteButton.Enable = 'off';
                app.GraphicsConfigurationsPanel.Enable = 'off';
            else
                % Update selection to first item
                NoiseListBoxValueChanged(app);
            end
        end

        % Value changed function: NondimensionalformCheckBox
        function NondimensionalformCheckBoxValueChanged(app, event)
            value = app.NondimensionalformCheckBox.Value;
            if value == 1
                app.NonDimensionalEditField.Enable = "on";
            else
                app.NonDimensionalEditField.Enable ="off";
                app.NonDimensionalEditField.Value = 1;
            end
        end

        % Value changed function: NonDimensionalEditField
        function NonDimensionalEditFieldValueChanged(app, event)
            value = app.NonDimensionalEditField.Value;
            if value ~= 0
                NoiseListBoxValueChanged(app);
            end
        
        end

        % Value changed function: XLabelEditField, 
        % ...and 1 other component
        function XLabelEditFieldValueChanged(app, event)
            value = app.XLabelEditField.Value;
            app.UIAxes.XLabel.Interpreter = app.XLabel_InterpreterDropDown.Value;
            app.UIAxes.XLabel.String = value;
            app.UIAxes.XLabel.FontWeight = 'bold';
            app.UIAxes.XLabel.FontSize = 12;
        end

        % Value changed function: YLabelEditField, 
        % ...and 1 other component
        function YLabelEditFieldValueChanged(app, event)
            value = app.YLabelEditField.Value;
            app.UIAxes.YLabel.Interpreter = app.YLabel_InterpreterDropDown.Value;
            app.UIAxes.YLabel.String = value;
            app.UIAxes.YLabel.FontWeight = 'bold';
            app.UIAxes.YLabel.FontSize = 12;
        end

        % Value changed function: TitleEditField, title_InterpreterDropDown
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            app.UIAxes.Title.Interpreter = app.title_InterpreterDropDown.Value;
            app.UIAxes.Title.String = value;
            app.UIAxes.Title.FontWeight = 'bold';
        end

        % Value changed function: AutofrequencyaxisCheckBox
        function AutofrequencyaxisCheckBoxValueChanged(app, event)
            value = app.AutofrequencyaxisCheckBox.Value;
            if value == 0
                app.X0_LimitEditField.Enable ="on";
                app.Xf_LimitEditField.Enable ="on";

                app.X0_LimitEditField.Value = app.UIAxes.XLim(1);
                app.Xf_LimitEditField.Value = app.UIAxes.XLim(2);
            else
                app.X0_LimitEditField.Enable ="off";
                app.Xf_LimitEditField.Enable ="off";
                app.UIAxes.XLimMode = "auto";
            end
        end

        % Value changed function: AutoamplitudeaxisCheckBox
        function AutoamplitudeaxisCheckBoxValueChanged(app, event)
            value = app.AutoamplitudeaxisCheckBox.Value;
            if value == 0
                app.Y0_LimitEditField.Enable ="on";
                app.Yf_LimitEditField.Enable ="on";

                app.Y0_LimitEditField.Value = app.UIAxes.YLim(1);
                app.Yf_LimitEditField.Value = app.UIAxes.YLim(2);
            else
                app.Y0_LimitEditField.Enable ="off";
                app.Yf_LimitEditField.Enable ="off";
                app.UIAxes.YLimMode = "auto";
            end
        end

        % Value changed function: X0_LimitEditField, Xf_LimitEditField
        function X0_LimitEditFieldValueChanged(app, event)
            value_x0 = app.X0_LimitEditField.Value;
            value_xf = app.Xf_LimitEditField.Value;

            if value_xf > value_x0
                app.UIAxes.XLim= [value_x0, value_xf];
            end
            
        end

        % Value changed function: Y0_LimitEditField, Yf_LimitEditField
        function Y0_LimitEditFieldValueChanged(app, event)
            value_y0 = app.Y0_LimitEditField.Value;
            value_yf = app.Yf_LimitEditField.Value;
            
            if value_yf > value_y0
                app.UIAxes.YLim = [value_y0, value_yf];
            end
            
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % Get resolution
            dpi = app.DPIEditField.Value;  % Pegue o valor da caixa de entrada de DPI
            
     
            % Selecionar o nome do arquivo e caminho para salvar
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tiff';'*.pdf'}, 'Salvar Gráfico como');
            
            if isequal(file, 0)
                % Caso o usuário cancele a seleção do arquivo, não faça nada
                return;
            else
                % Caminho completo do arquivo
                fullFileName = fullfile(path, file);

                % fig = figure();
                % copiedAxes = copyobj(app.UIAxes, fig);
                % set(copiedAxes, 'Position', get(gcf, 'Position'));

                % [~, ~, ext] = fileparts(fullFileName);
                
                % Salvar o gráfico com a resolução fornecida em DPI
                % print(fig, fullFileName, '-dpng', ['-r', num2str(dpi)]);  % Salva com a resolução especificada
                exportgraphics(app.UIAxes, fullFileName, 'Resolution', dpi);

                % switch ext
                %     case '.png'
                %         print(fig, fullFileName, '-dpng', ['-r', num2str(dpi)]);  % Salva como PNG
                %     case '.jpg'
                %         print(fig, fullFileName, '-djpeg', ['-r', num2str(dpi)]);  % Salva como JPEG
                %     case '.eps'
                %         print(fig, fullFileName, '-depsc', ['-r', num2str(dpi)]);  % Salva como EPS (vetorial)
                %     case '.pdf'
                %         print(fig, fullFileName, '-dpdf', ['-r', num2str(dpi)]);   % Salva como PDF (vetorial)
                %     case '.tiff'
                %         print(fig, fullFileName, '-dtiff', ['-r', num2str(dpi)]);  % Salva como TIFF
                %     otherwise
                %         warning('Formato não suportado.');
                % end
                % 
                % % Fechar a figura temporária após salvar
                % close(fig);
    end
        end

        % Value changed function: AerodynamicsListBox
        function AerodynamicsListBoxValueChanged(app, event)
            % PLOT FUNCTION
            %Clear axes
            cla(app.UIAxes_thrust)
            cla(app.UIAxes_torque)
            
            % Get values
            values = app.AerodynamicsListBox.Value;
            valuesIndex = app.AerodynamicsListBox.ValueIndex;
            
            if ~isvector(valuesIndex)
                valuesIndex = [valuesIndex];
            end

            legendNames = cell(1 , length(valuesIndex));
            
            % Plot
            for i=1:length(values)
                if i==2
                    hold(app.UIAxes_thrust, 'on');
                    hold(app.UIAxes_torque, 'on');
                end
                value = values{i};
                legendNames{i} = app.AerodynamicsListBox.Items{valuesIndex(i)};
                plot(app.UIAxes_thrust, value(:,3), value(:, 2), '-o', MarkerFaceColor='black', MarkerSize=3);
                plot(app.UIAxes_torque, value(:, 3), value(:, 1), '-o', MarkerFaceColor='black', MarkerSize=3);
            end
            hold(app.UIAxes_thrust, 'off');
            hold(app.UIAxes_torque, 'off');

            xlabel(app.UIAxes_thrust, 'RPM');
            ylabel(app.UIAxes_thrust, 'Thrust');

            xlabel(app.UIAxes_torque, 'RPM');
            ylabel(app.UIAxes_torque, 'Torque');
           
            % Add legend and 
            leg1 = legend(app.UIAxes_thrust, legendNames, 'Interpreter', 'none');
            leg2 = legend(app.UIAxes_torque, legendNames, 'Interpreter', 'none');
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {361, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NoiseListBoxLabel = uilabel(app.LeftPanel);
            app.NoiseListBoxLabel.Text = 'Noise';
            app.NoiseListBox = uilistbox(app.LeftPanel);
            app.NoiseListBox.Items = {};
            app.NoiseListBox.Multiselect = 'on';
            app.NoiseListBox.ValueChangedFcn = createCallbackFcn(app, @NoiseListBoxValueChanged, true);
            app.NoiseListBox.Tooltip = {''};
            app.NoiseListBox.Value = {};
            app.deleteButton = uibutton(app.LeftPanel, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete';
            app.openButton = uibutton(app.LeftPanel, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Text = 'open';
            app.GraphicsConfigurationsPanel = uipanel(app.LeftPanel);
            app.GraphicsConfigurationsPanel.Enable = 'off';
            app.GraphicsConfigurationsPanel.Title = 'Graphics Configurations';
            app.AutofrequencyaxisCheckBox = uicheckbox(app.GraphicsConfigurationsPanel);
            app.AutofrequencyaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutofrequencyaxisCheckBoxValueChanged, true);
            app.AutofrequencyaxisCheckBox.Text = 'Auto frequency axis';
            app.AutofrequencyaxisCheckBox.Value = true;
            app.AutoamplitudeaxisCheckBox = uicheckbox(app.GraphicsConfigurationsPanel);
            app.AutoamplitudeaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoamplitudeaxisCheckBoxValueChanged, true);
            app.AutoamplitudeaxisCheckBox.Text = 'Auto amplitude axis';
            app.AutoamplitudeaxisCheckBox.Value = true;
            app.X0_LimitEditField = uieditfield(app.GraphicsConfigurationsPanel, 'numeric');
            app.X0_LimitEditField.Limits = [0 Inf];
            app.X0_LimitEditField.ValueChangedFcn = createCallbackFcn(app, @X0_LimitEditFieldValueChanged, true);
            app.X0_LimitEditField.Enable = 'off';
            app.Xf_LimitEditField = uieditfield(app.GraphicsConfigurationsPanel, 'numeric');
            app.Xf_LimitEditField.Limits = [0 Inf];
            app.Xf_LimitEditField.ValueChangedFcn = createCallbackFcn(app, @X0_LimitEditFieldValueChanged, true);
            app.Xf_LimitEditField.Enable = 'off';
            app.Y0_LimitEditField = uieditfield(app.GraphicsConfigurationsPanel, 'numeric');
            app.Y0_LimitEditField.Limits = [0 Inf];
            app.Y0_LimitEditField.ValueChangedFcn = createCallbackFcn(app, @Y0_LimitEditFieldValueChanged, true);
            app.Y0_LimitEditField.Enable = 'off';
            app.Yf_LimitEditField = uieditfield(app.GraphicsConfigurationsPanel, 'numeric');
            app.Yf_LimitEditField.Limits = [0 Inf];
            app.Yf_LimitEditField.ValueChangedFcn = createCallbackFcn(app, @Y0_LimitEditFieldValueChanged, true);
            app.Yf_LimitEditField.Enable = 'off';
            app.NondimensionalformCheckBox = uicheckbox(app.GraphicsConfigurationsPanel);
            app.NondimensionalformCheckBox.ValueChangedFcn = createCallbackFcn(app, @NondimensionalformCheckBoxValueChanged, true);
            app.NondimensionalformCheckBox.Text = 'Non-dimensional form';
            app.NonDimensionalEditField = uieditfield(app.GraphicsConfigurationsPanel, 'numeric');
            app.NonDimensionalEditField.Limits = [1 Inf];
            app.NonDimensionalEditField.ValueChangedFcn = createCallbackFcn(app, @NonDimensionalEditFieldValueChanged, true);
            app.NonDimensionalEditField.Enable = 'off';
            app.NonDimensionalEditField.Value = 1;
            app.XLabelEditFieldLabel = uilabel(app.GraphicsConfigurationsPanel);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.GraphicsConfigurationsPanel, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.XLabelEditField.Value = 'f [Hz]';
            app.YLabelEditFieldLabel = uilabel(app.GraphicsConfigurationsPanel);
            app.YLabelEditFieldLabel.Text = 'Y Label';
            app.YLabelEditField = uieditfield(app.GraphicsConfigurationsPanel, 'text');
            app.YLabelEditField.ValueChangedFcn = createCallbackFcn(app, @YLabelEditFieldValueChanged, true);
            app.YLabelEditField.Value = 'SPL [dB]';
            app.TitleEditFieldLabel = uilabel(app.GraphicsConfigurationsPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.GraphicsConfigurationsPanel, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.TitleEditField.Value = 'Spectrum';
            app.title_InterpreterDropDown = uidropdown(app.GraphicsConfigurationsPanel);
            app.title_InterpreterDropDown.Items = {'tex', 'latex'};
            app.title_InterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.title_InterpreterDropDown.Value = 'tex';
            app.XLabel_InterpreterDropDown = uidropdown(app.GraphicsConfigurationsPanel);
            app.XLabel_InterpreterDropDown.Items = {'tex', 'latex'};
            app.XLabel_InterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.XLabel_InterpreterDropDown.Value = 'tex';
            app.YLabel_InterpreterDropDown = uidropdown(app.GraphicsConfigurationsPanel);
            app.YLabel_InterpreterDropDown.Items = {'tex', 'latex'};
            app.YLabel_InterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @YLabelEditFieldValueChanged, true);
            app.YLabel_InterpreterDropDown.Value = 'tex';
            app.SaveImageButton = uibutton(app.GraphicsConfigurationsPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.DPIEditFieldLabel = uilabel(app.GraphicsConfigurationsPanel);
            app.DPIEditFieldLabel.Text = 'DPI';
            app.DPIEditField = uieditfield(app.GraphicsConfigurationsPanel, 'numeric');
            app.DPIEditField.Limits = [72 600];
            app.DPIEditField.Value = 300;
            app.AerodynamicsListBoxLabel = uilabel(app.LeftPanel);
            app.AerodynamicsListBoxLabel.Text = 'Aerodynamics';
            app.AerodynamicsListBox = uilistbox(app.LeftPanel);
            app.AerodynamicsListBox.Items = {};
            app.AerodynamicsListBox.Multiselect = 'on';
            app.AerodynamicsListBox.ValueChangedFcn = createCallbackFcn(app, @AerodynamicsListBoxValueChanged, true);
            app.AerodynamicsListBox.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'f [Hz]')
            ylabel(app.UIAxes, 'SPL [dB]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_thrust = uiaxes(app.RightPanel);
            title(app.UIAxes_thrust, 'Thrust')
            xlabel(app.UIAxes_thrust, 'X')
            ylabel(app.UIAxes_thrust, 'Y')
            zlabel(app.UIAxes_thrust, 'Z')
            app.UIAxes_torque = uiaxes(app.RightPanel);
            title(app.UIAxes_torque, 'Torque')
            xlabel(app.UIAxes_torque, 'X')
            ylabel(app.UIAxes_torque, 'Y')
            zlabel(app.UIAxes_torque, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end